classdef Julia_Sets < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        cEditFieldLabel                matlab.ui.control.Label
        cEditField                     matlab.ui.control.NumericEditField
        nEditFieldLabel                matlab.ui.control.Label
        nEditField                     matlab.ui.control.NumericEditField
        IniciarButton                  matlab.ui.control.Button
        ConjuntodeJuliaLabel           matlab.ui.control.Label
        FuenteJuliaSetsMathWorksLabel  matlab.ui.control.Label
        iEditFieldLabel                matlab.ui.control.Label
        iEditField                     matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)


            W = f_julia_set(app.cEditField.Value + (app.iEditField.Value*1i), app.nEditField.Value);
            imshow(W, 'Parent', app.UIAxes);

            map = [];
            % negro ->rojo
            n = 10;
            for i=1:n
                map = [map;[i/n,0,0]];
            end

            % rojo -> azul
            for i=1:n
                map = [map;[1-i/n,0,i/n]];
            end
            % azul -> aguamarina
            for i=1:n
                map = [map;[0,i/n,1]];
            end

            % aguamarina->verde
            for i=1:n
                map = [map;[0,1,1-i/n]];
            end
            % verde->amarillo
            for i=1:n
                map = [map;[i/n,1,0]];
            end

            colormap(app.UIAxes, map);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditField.Value = 0.4;
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.nEditField.Value = 3;
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar';
            app.ConjuntodeJuliaLabel = uilabel(app.UIFigure);
            app.ConjuntodeJuliaLabel.Text = 'Conjunto de Julia';
            app.FuenteJuliaSetsMathWorksLabel = uilabel(app.UIFigure);
            app.FuenteJuliaSetsMathWorksLabel.Text = 'Fuente: Julia Sets MathWorks';
            app.iEditFieldLabel = uilabel(app.UIFigure);
            app.iEditFieldLabel.Text = 'i';
            app.iEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Julia_Sets

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end